classdef maelingarapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TmamerkiButton                 matlab.ui.control.Button
        MlingButton                    matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        FlattopButton                  matlab.ui.control.RadioButton
        HanningButton                  matlab.ui.control.RadioButton
        MliforritfyrirsveiflufriLabel  matlab.ui.control.Label
        FjldimligildaEditField         matlab.ui.control.NumericEditField
        FjldimligildaEditFieldLabel    matlab.ui.control.Label
        SfnunartniEditField            matlab.ui.control.NumericEditField
        SfnunartniEditFieldLabel       matlab.ui.control.Label
        FFTgreiningButton              matlab.ui.control.Button
        VistaskrEditField              matlab.ui.control.EditField
        VistaskrEditFieldLabel         matlab.ui.control.Label
        VistaButton                    matlab.ui.control.Button
        InnlesturButton                matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InnlesturButton
        function svinn(app, event)
            disp('svinn hafid');
            
            % Lesa mælingu úr skrá sem hefur verið vistuð
            [lename,pathname] = uigetfile('*.mat','Select file name');
            logname = [pathname lename];
            disp(logname);
            eval(['load ' logname]);
            %fs =  % Thurfum ekki thvi eval naer i gogn
            %N = size(xAcc,1);  % Thurfum ekki thvi eval naer i gogn

            % Setja N og fs inn í NumericEditFields í appi
            Soft = findobj(gcbf,'Tag','Softidni');
            Soft.Value = fs;
            
            Fjol = findobj(gcbf,'Tag','Fjolmael');
            Fjol.Value = N;
            
            % Búa til tímaás
            dt = 1/fs;
            tN = (N-1)/fs;
            t = 0:dt:tN;
            
            % Vista mælingu (í glugganum)
            xyd = [t'  xAcc];
            set(gcbf,'UserData',xyd);
            
            % Teikna mælingu
            figure(1)
            clf
            hold on
            plot(t,xAcc(1:N,1), 'y');    %Plotta nema 1
            plot(t,xAcc(1:N,2), 'r');    %Plotta nema 2
            plot(t,xAcc(1:N,3), 'g');    %Plotta nema 3
            plot(t,xAcc(1:N,4), 'b');    %Plotta nema 4
            hold off
            title('Mæling');
            xlabel('Tími [sek]');
            ylabel('Hröðun [m/sek2]');
            legend('Nemi 1', 'Nemi 2', 'Nemi 3', 'Nemi 4');
        end

        % Button pushed function: FFTgreiningButton
        function svfft(app, event)
            disp('FFT greining hafin');
            
            % Sækja söfnunartíðni og fjöldi mæligilda fyrir mæling
            Soft = findobj(gcbf,'Tag','Softidni');
            fs = Soft.Value;
            Fjol = findobj(gcbf,'Tag','Fjolmael');
            N = Fjol.Value;
            
            % Sækja mælingu
            xyd=get(gcbf,'UserData');
            xAcc  = xyd(:,2:end);
            
            % Búa til tíðniás
            n = N/2;
            w = fs*(0:n-1)./(2*(n-1));
            wt = 2*pi*(1:N)'/(N+1);
            
            % Lesa og skilgreina gluggun
            radiohan = findobj(gcbf,'String','Hanning');
            radiofla = findobj(gcbf,'String','Flat Top');
            %valhan = radiohan.Value;
            %valfla = radiofla.Value;
            
            valhan = findobj(gcbf,'Tag','Hanning').Value;
            valfla = findobj(gcbf,'Tag','Flat top').Value;
            
            disp('valhan:');
            disp(valhan);
            disp('valfla:');
            disp(valfla);
            
            if(valhan)
                % Hanning gluggi
                win = 1 -cos(wt);
                bandv = 1.5;
            elseif(valfla)
                % Flat top gluggi
                win = 1-1.93*cos(wt)+1.29*cos(2*wt)-0.388*cos(3*wt)+0.0322*cos(4*wt);
                bandv = 3.77;
            end
            
            % Reikna Fouriervörpun og aflróf fyrir einn mælinema
            figure(2)
            clf;
            g_psd = fft(win.*xAcc(1:N,2));
            Amp =sqrt( 4*((abs(g_psd)/N).^2));
            hold on
            plot(w(2:n),Amp(2:n),'r')
            g_psd = fft(win.*xAcc(1:N,3));
            Amp =sqrt( 4*((abs(g_psd)/N).^2));
            title('FFT greining - Nemi 2');
            xlabel('Tíðni [Hz]');
            ylabel('Hröðun [m/sek2]');
            figure(3)
            clf;
            plot(w(2:n),Amp(2:n),'g')
            g_psd = fft(win.*xAcc(1:N,4));
            Amp =sqrt( 4*((abs(g_psd)/N).^2));
            title('FFT greining - Nemi 3');
            xlabel('Tíðni [Hz]');
            ylabel('Hröðun [m/sek2]');
            figure(4)
            clf;
            plot(w(2:n),Amp(2:n),'b')
            title('FFT greining - Nemi 4');
            xlabel('Tíðni [Hz]');
            ylabel('Hröðun [m/sek2]');
            hold off
        end

        % Button pushed function: VistaButton
        function svvis(app, event)
            disp('svvis hafid');
            
            % Sækja upplýsingar til að vista
            Utskr = findobj(gcbf,'Tag','Utskra');
            fname = Utskr.Value;
            %fname = 'Nemi3'
            Soft = findobj(gcbf,'Tag','Softidni');
            fs = Soft.Value;
            %fs = 2500;
            Fjol = findobj(gcbf,'Tag','Fjolmael');
            N = Fjol.Value;
            %N=2500;
            
            % Sækja mælingu
            xyd=get(gcbf,'UserData');
            xAcc  = xyd(:,2:end);
           eval(['save ' fname ' xAcc fs N ']);
        end

        % Button pushed function: MlingButton
        function svmae(app, event)
           disp('svmae hafid');
    % Sækja söfnunartíðni og fjöldi mæligilda fyrir mæling
            Soft = findobj(gcbf,'Tag','Softidni');
            fs = Soft.Value;
    
            disp('fs:');
            disp(fs);
            
            Fjol = findobj(gcbf,'Tag','Fjolmael');
            N = Fjol.Value;

            disp('fs:');
            disp(fs);
            disp('N:');
            disp(N);
            
        % Mæla
    %save('ADread.inp','fs','N','-ascii');
    %!ADread   
    %load('ADread.out','-ascii') ;
            xAcc = ADread(4,fs,N);
        % Búa til tímaás
            dt = 1/fs ;
            tN = (N-1)/fs ;
            t = 0:dt:tN ;
        % Vista tímaás og mælingu í glugga
            xyd = [t'  xAcc];
            set(gcbf,'UserData',xyd) ;
        % Teikna mælingu
            figure(1)
            plot(t,xAcc);
            title('Mæling');
            xlabel('Tími [sek]');
            ylabel('Hröðun [m/sek2]');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % Tekur inn gildið á radio tökkunum og lætur radio takkana fá
            % sama gildi og þeir voru með til að byrja með. S.s.
            % vitagagnslaust fall.

            disp('Er i falli ButtonGroupSelectionChanged');
            Hanning_is_selected = findobj(gcbf,'Tag','Hanning').Value;
            Flat_top_is_selected = findobj(gcbf,'Tag','Flat top').Value;

            if (Flat_top_is_selected)
                disp('Er i flat top');
                svflat();
            end
            if (Hanning_is_selected)
                disp('Er i hanning');
                svhan();
            end
        end

        % Button pushed function: TmamerkiButton
        function svtim(app, event)
            % Teiknar tímaháð gröf fyrir alla mælinema     
            disp('svtim hafið. Teiknum tímaóháð gröf fyrir alla mælinema.');
            
            % Sækja söfnunartíðni og fjöldi mæligilda fyrir mæling
            Soft = findobj(gcbf,'Tag','Softidni');
            fs = Soft.Value;
            Fjol = findobj(gcbf,'Tag','Fjolmael');
            N = Fjol.Value;
            
            timi_a_milli_maelinga = 1/fs;
            timi = [];
            for i = 1:N
                timi(i) = (i-1)*timi_a_milli_maelinga;
            end
            
            % Sækja mælingu
            xyd=get(gcbf,'UserData');
            xAcc  = xyd(:,2:end);            

            figure(2)
            clf;
            hold on
            plot(timi(1:N),xAcc(1:N,2),'r')
            title('FFT greining - Nemi 2');
            xlabel('Tími [Sek]');
            ylabel('Hröðun [m/sek2]');
            hold off            
            figure(3)
            clf;
            hold on
            plot(timi(1:N),xAcc(1:N,3),'g')
            title('FFT greining - Nemi 3');
            xlabel('Tími [Sek]');
            ylabel('Hröðun [m/sek2]');
            hold off
            figure(4)
            clf;
            hold on
            plot(timi(1:N),xAcc(1:N,4),'b')
            title('FFT greining - Nemi 4');
            xlabel('Tími [Sek]');
            ylabel('Hröðun [m/sek2]');
            hold off            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {346, '1x', 229};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InnlesturButton = uibutton(app.LeftPanel, 'push');
            app.InnlesturButton.ButtonPushedFcn = createCallbackFcn(app, @svinn, true);
            app.InnlesturButton.Text = 'Innlestur';
            app.VistaButton = uibutton(app.LeftPanel, 'push');
            app.VistaButton.ButtonPushedFcn = createCallbackFcn(app, @svvis, true);
            app.VistaButton.Text = 'Vista';
            app.VistaskrEditFieldLabel = uilabel(app.LeftPanel);
            app.VistaskrEditFieldLabel.Text = 'Vista í skrá:';
            app.VistaskrEditField = uieditfield(app.LeftPanel, 'text');
            app.VistaskrEditField.Tag = 'Utskra';
            app.FFTgreiningButton = uibutton(app.LeftPanel, 'push');
            app.FFTgreiningButton.ButtonPushedFcn = createCallbackFcn(app, @svfft, true);
            app.FFTgreiningButton.Text = 'FFT greining';
            app.SfnunartniEditFieldLabel = uilabel(app.LeftPanel);
            app.SfnunartniEditFieldLabel.Text = 'Söfnunartíðni';
            app.SfnunartniEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SfnunartniEditField.Tag = 'Softidni';
            app.FjldimligildaEditFieldLabel = uilabel(app.LeftPanel);
            app.FjldimligildaEditFieldLabel.Text = 'Fjöldi mæligilda';
            app.FjldimligildaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FjldimligildaEditField.Tag = 'Fjolmael';
            app.MliforritfyrirsveiflufriLabel = uilabel(app.LeftPanel);
            app.MliforritfyrirsveiflufriLabel.Text = {'Mæliforrit fyrir'; 'sveiflufræði'};
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.HanningButton = uiradiobutton(app.ButtonGroup);
            app.HanningButton.Tag = 'Hanning';
            app.HanningButton.Text = 'Hanning';
            app.HanningButton.Value = true;
            app.FlattopButton = uiradiobutton(app.ButtonGroup);
            app.FlattopButton.Tag = 'Flat top';
            app.FlattopButton.Text = 'Flat top';
            app.MlingButton = uibutton(app.LeftPanel, 'push');
            app.MlingButton.ButtonPushedFcn = createCallbackFcn(app, @svmae, true);
            app.MlingButton.Text = 'Mæling';
            app.TmamerkiButton = uibutton(app.LeftPanel, 'push');
            app.TmamerkiButton.ButtonPushedFcn = createCallbackFcn(app, @svtim, true);
            app.TmamerkiButton.Text = 'Tímamerki';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maelingarapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end